classdef ReachabilityGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RobotModelTab                   matlab.ui.container.Tab
        SelectaPredefinedRobotDropDown  matlab.ui.control.DropDown
        SelectaPredefinedRobotLabel     matlab.ui.control.Label
        RobotModelSwitch                matlab.ui.control.RockerSwitch
        ShowVoxelizedWorkspaceButton    matlab.ui.control.Button
        VoxelizeWorkspaceButton         matlab.ui.control.Button
        VoxelsperDimensionEditField     matlab.ui.control.NumericEditField
        VoxelsperDimensionLabel         matlab.ui.control.Label
        ShowWorkspaceButton             matlab.ui.control.Button
        ImportShowRobotButton           matlab.ui.control.Button
        GenerateWorkspaceButton         matlab.ui.control.Button
        NumberofConfigurationsEditField  matlab.ui.control.NumericEditField
        NumberofConfigurationsLabel     matlab.ui.control.Label
        RobotImportedLamp               matlab.ui.control.Lamp
        RobotImportedLampLabel          matlab.ui.control.Label
        BrowseURDFButton                matlab.ui.control.Button
        URDFFileNameEditField           matlab.ui.control.EditField
        URDFFileNameLabel               matlab.ui.control.Label
        ReachabilityAnalysisTab         matlab.ui.container.Tab
        VisualizationSwitch             matlab.ui.control.Switch
        VisualizationSwitchLabel        matlab.ui.control.Label
        PosesperVoxelNumeric            matlab.ui.control.NumericEditField
        PosesperVoxelEditFieldLabel     matlab.ui.control.Label
        PhiSliceNumeric                 matlab.ui.control.EditField
        ThetaSliceNumeric               matlab.ui.control.EditField
        PhiSlicesSlider                 matlab.ui.control.Slider
        PhiSlicesSliderLabel            matlab.ui.control.Label
        ThetaSlicesSlider               matlab.ui.control.Slider
        ThetaSlicesSliderLabel          matlab.ui.control.Label
        CheckReachabilityButton         matlab.ui.control.Button
        GenerateReachabilityMapButton   matlab.ui.control.Button
        SelfcollisionSwitch             matlab.ui.control.Switch
        SelfcollisionSwitchLabel        matlab.ui.control.Label
        ReachableLamp                   matlab.ui.control.Lamp
        ReachableLampLabel              matlab.ui.control.Label
        MapGenTextArea                  matlab.ui.control.TextArea
        PointReachTextArea              matlab.ui.control.TextArea
        PRAEditField                    matlab.ui.control.NumericEditField
        ALabel                          matlab.ui.control.Label
        PRBEditField                    matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        PRCEditField                    matlab.ui.control.NumericEditField
        ZLabel_2                        matlab.ui.control.Label
        PRZEditField                    matlab.ui.control.NumericEditField
        ZLabel                          matlab.ui.control.Label
        PRYEditField                    matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        PRXEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
         % Description
        robot_model_pair = dictionary("ABB IRB 120", "abbIrb120", "ABB IRB 1600", "abbIrb1600", ...
            "FANUC LR Mate 200iB", "fanucLRMate200ib", "KINOVA® Gen3", "kinovaGen3", ...
            "KUKA LBR iiwa 7 R800", "kukaIiwa7", "Universal Robots UR10", "universalUR10", ...
            "Yaskawa Motoman MH5", "yaskawaMotomanMH5");% Robot model and name for import
        
        model_ee_pair = dictionary("ABB IRB 120", "tool0", "ABB IRB 1600", "tool0", ...
            "FANUC LR Mate 200iB", "tool0", "KINOVA® Gen3", "EndEffector_Link", ...
            "KUKA LBR iiwa 7 R800", "iiwa_link_ee_kuka", "Universal Robots UR10", "tool0", ...
            "Yaskawa Motoman MH5", "link_t");% Robot model and name for import
        
        model_base_pair = dictionary("ABB IRB 120", "base_link", "ABB IRB 1600", "base_link", ...
            "FANUC LR Mate 200iB", "base_link", "KINOVA® Gen3", "base_link", ...
            "KUKA LBR iiwa 7 R800", "world", "Universal Robots UR10", "world", ...
            "Yaskawa Motoman MH5", "base_link");% Robot model and name for import
    end
    
    properties (Access = public)
        robot_urdf % Robot urdf file stored here
        robot_model % Pre-defined robot models stored here
        rbt_model % Selected rigidBodyTree for the robot
        lower_limits
        upper_limits
        X_ee_array
        Y_ee_array
        Z_ee_array
        voxelsPerDim
        X_mesh
        Y_mesh
        Z_mesh
        R_mesh
        min_limits
        max_limits
        R_index
        both_halves
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusTextArea.Value = "App is ready.";
        end

        % Button pushed function: BrowseURDFButton
        function BrowseURDFButtonPushed(app, event)
            [filename, path] = uigetfile('*.urdf');
            app.URDFFileNameEditField.Value = filename;
            app.UIFigure.focus();
            try
                app.robot_urdf = importrobot([path, filename]);
                app.robot_urdf.DataFormat = 'column'; % Change data format
    
                app.lower_limits = [];
                app.upper_limits = [];
                
                % Obtaining joint limits from the robot's URDF
                for i=1:length(app.robot_urdf.Bodies)
                    if app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(1)~=0 && app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(2)~=0
                        if app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(1)==-inf
                            app.lower_limits(end+1) = -pi; % joint lower limit in rad
                        else
                            app.lower_limits(end+1) = app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(1); % joint lower limit in rad
                        end
                        if app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(2)==inf
                            app.upper_limits(end+1) = pi; % joint upper limit in rad
                        else
                            app.upper_limits(end+1) = app.robot_urdf.Bodies{1,i}.Joint.PositionLimits(2); % joint upper limit in rad
                        end
                    end
                end
                
                app.RobotImportedLamp.Color=[0.00, 1.00, 0.00];
                app.StatusTextArea.Value = "Robot URDF is imported successfully.";
            catch
                app.RobotImportedLamp.Color=[1.00, 0.00, 0.00];
                app.StatusTextArea.Value = "Problem reading the robot URDF.";
            end
            
        end

        % Value changed function: URDFFileNameEditField
        function URDFFileNameEditFieldValueChanged(app, event)
            value = app.URDFFileNameEditField.Value;
        end

        % Button pushed function: GenerateWorkspaceButton
        function GenerateWorkspaceButtonPushed(app, event)
            total_points = app.NumberofConfigurationsEditField.Value;
            app.StatusTextArea.Value = "Workspace generation in progress...";
            pause(0.01);
            if app.RobotModelSwitch.Value == "Import URDF"
                [app.X_ee_array, app.Y_ee_array, app.Z_ee_array] = getWorkspaceRandom(app.rbt_model, app.lower_limits, app.upper_limits, total_points, 'base_link', 'tool0');
            elseif app.RobotModelSwitch.Value == "Select Model"
                [app.X_ee_array, app.Y_ee_array, app.Z_ee_array] = getWorkspaceRandom(app.rbt_model, app.lower_limits, app.upper_limits, total_points, app.model_base_pair(app.SelectaPredefinedRobotDropDown.Value), app.model_ee_pair(app.SelectaPredefinedRobotDropDown.Value));
            end
            app.StatusTextArea.Value = "Robot workspace is generated.";
            figure("Name", 'workspace');
            scatter3(app.X_ee_array(:), app.Y_ee_array(:), app.Z_ee_array(:), 1.0, 'x');
            title('Workspace');
            axis equal
            hold on
            show(app.rbt_model);
            % pause(0.01);
        end

        % Button pushed function: ImportShowRobotButton
        function ImportShowRobotButtonPushed(app, event)
            if app.RobotModelSwitch.Value == "Import URDF"
                if isa(app.robot_urdf, 'rigidBodyTree') == 1
                    app.rbt_model = app.robot_urdf;
                    app.rbt_model.DataFormat = 'column'; % Change data format
    
                    app.lower_limits = [];
                    app.upper_limits = [];
                    
                    % Obtaining joint limits from the robot's URDF
                    for i=1:length(app.rbt_model.Bodies)
                        if app.rbt_model.Bodies{1,i}.Joint.PositionLimits(1)~=0 && app.rbt_model.Bodies{1,i}.Joint.PositionLimits(2)~=0
                            app.lower_limits(end+1) = app.rbt_model.Bodies{1,i}.Joint.PositionLimits(1); % joint lower limit in rad
                            app.upper_limits(end+1) = app.rbt_model.Bodies{1,i}.Joint.PositionLimits(2); % joint upper limit in rad
                        end
                    end
                    close(findobj('type','figure','name','robot_model'))
                    figure("Name", 'robot_model');
                    show(app.robot_urdf);
                    title('Robot View');
                end
            elseif app.RobotModelSwitch.Value == "Select Model"
                try
                    app.robot_model = loadrobot(app.robot_model_pair(app.SelectaPredefinedRobotDropDown.Value));
                    app.rbt_model = app.robot_model;
                    app.rbt_model.DataFormat = 'column'; % Change data format
    
                    app.lower_limits = [];
                    app.upper_limits = [];
                    
                    % Obtaining joint limits from the robot's URDF
                    for i=1:length(app.rbt_model.Bodies)
                        if app.rbt_model.Bodies{1,i}.Joint.PositionLimits(1)~=0 && app.rbt_model.Bodies{1,i}.Joint.PositionLimits(2)~=0
                            app.lower_limits(end+1) = app.rbt_model.Bodies{1,i}.Joint.PositionLimits(1); % joint lower limit in rad
                            app.upper_limits(end+1) = app.rbt_model.Bodies{1,i}.Joint.PositionLimits(2); % joint upper limit in rad
                        end
                    end
                    app.RobotImportedLamp.Color=[0.00, 1.00, 0.00];
                    app.StatusTextArea.Value = "Robot model is imported successfully.";
                    close(findobj('type','figure','name','robot_model'))
                    figure("Name", 'robot_model');
                    show(app.robot_model);
                    title('Robot View');
                catch
                    app.StatusTextArea.Value = "Wrong robot model name";
                end
            end
        end

        % Button pushed function: ShowWorkspaceButton
        function ShowWorkspaceButtonPushed(app, event)
            close(findobj('type','figure','name','workspace'))
            figure("Name", 'workspace');
            scatter3(app.X_ee_array(:), app.Y_ee_array(:), app.Z_ee_array(:), 1.0, 'x');
            title('Workspace');
            axis equal
            hold on
            show(app.rbt_model);
        end

        % Button pushed function: VoxelizeWorkspaceButton
        function VoxelizeWorkspaceButtonPushed(app, event)
            app.voxelsPerDim = app.VoxelsperDimensionEditField.Value;
            app.StatusTextArea.Value = "Workspace voxelization in progress...";
            pause(0.01);
            [app.X_mesh, app.Y_mesh, app.Z_mesh, app.R_mesh, app.min_limits, app.max_limits] = voxelizeWorkspace(app.X_ee_array, app.Y_ee_array, app.Z_ee_array, app.voxelsPerDim);
            app.StatusTextArea.Value = "Voxelized worksapce is calculated.";
    
            figure("Name", "voxelworkspace");
            scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), app.R_mesh(:)*50, 's', 'filled', 'MarkerFaceAlpha', 0.2);
            title('Voxelized Workspace');
            axis equal
            hold on
            show(app.rbt_model);
        end

        % Button pushed function: ShowVoxelizedWorkspaceButton
        function ShowVoxelizedWorkspaceButtonPushed(app, event)
            if isa(app.R_mesh, 'double') == 1
                close(findobj('type','figure','name','voxelworkspace'))
                figure("Name", 'voxelworkspace');
                scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), app.R_mesh(:)*50, 's', 'filled', 'MarkerFaceAlpha', 0.2);
                title('Voxelized Workspace');
                axis equal
                hold on
                show(app.rbt_model);
            end
        end

        % Value changed function: RobotModelSwitch
        function RobotModelSwitchValueChanged(app, event)
            if app.RobotModelSwitch.Value == "Import URDF"
                if isa(app.robot_urdf, 'rigidBodyTree') == 1
                    close(findobj('type','figure','name','robot_model'))
                    app.rbt_model = app.robot_urdf;
                    app.RobotImportedLamp.Color=[0.00, 1.00, 0.00];
                    app.StatusTextArea.Value = "Robot URDF is imported successfully.";
                else
                    app.RobotImportedLamp.Color=[0.90, 0.90, 0.90];
                    app.StatusTextArea.Value = "Please browse for robot URDF.";
                end
            elseif app.RobotModelSwitch.Value == "Select Model"
                app.robot_model = loadrobot(app.robot_model_pair(app.SelectaPredefinedRobotDropDown.Value));
                app.rbt_model = app.robot_model;
                app.RobotImportedLamp.Color=[0.00, 1.00, 0.00];
                app.StatusTextArea.Value = "Robot model is imported successfully.";
            end
        end

        % Value changing function: PhiSlicesSlider
        function PhiSlicesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PhiSliceNumeric.Value = string(int32(changingValue));
            app.PosesperVoxelNumeric.Value = double(int32(changingValue)*int32(app.ThetaSlicesSlider.Value));
        end

        % Value changing function: ThetaSlicesSlider
        function ThetaSlicesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ThetaSliceNumeric.Value = string(int32(changingValue));
            app.PosesperVoxelNumeric.Value = double(int32(changingValue)*int32(app.PhiSlicesSlider.Value));
        end

        % Value changed function: ThetaSlicesSlider
        function ThetaSlicesSliderValueChanged(app, event)
            value = app.ThetaSlicesSlider.Value;
            app.ThetaSlicesSlider.Value = double(int32(value));
        end

        % Value changed function: PhiSlicesSlider
        function PhiSlicesSliderValueChanged(app, event)
            value = app.PhiSlicesSlider.Value;
            app.PhiSlicesSlider.Value = double(int32(value));
        end

        % Value changed function: SelfcollisionSwitch
        function SelfcollisionSwitchValueChanged(app, event)
            value = app.SelfcollisionSwitch.Value;
            if strcmp(value,'On')
                app.StatusTextArea.Value = "Self-collision check set to on";
            elseif strcmp(value,'Off')
                app.StatusTextArea.Value = "Self-collision check set to off";
            end
        end

        % Button pushed function: GenerateReachabilityMapButton
        function GenerateReachabilityMapButtonPushed(app, event)
            app.StatusTextArea.Value = "Reachability map generation in progress ...";
            pause(0.1);
            % Using Analytical IK for the ones that AIK is available
            if isfile(strcat(app.robot_model_pair(app.SelectaPredefinedRobotDropDown.Value),"_IK.m"))
                app.R_index = getReachabilityAIK(app.rbt_model, ...
                    app.min_limits, ...
                    app.max_limits, ...
                    app.VoxelsperDimensionEditField.Value, ...
                    app.R_mesh, ...
                    app.ThetaSlicesSlider.Value, ...
                    app.PhiSlicesSlider.Value, ...
                    strcat(app.robot_model_pair(app.SelectaPredefinedRobotDropDown.Value), "_IK"), ...
                    app.both_halves);
            else
                app.R_index = getReachability( ...
                    app.rbt_model, ...
                    app.min_limits, ...
                    app.max_limits, ...
                    app.VoxelsperDimensionEditField.Value, ...
                    app.R_mesh, ...
                    app.ThetaSlicesSlider.Value, ...
                    app.PhiSlicesSlider.Value);
            end
            figure("Name", 'reachability')
            scatter3(app.X_mesh(:), app.Y_mesh(:), app.Z_mesh(:), 50, app.R_index(:)/max(app.R_index(:)), 'filled', 'o', 'MarkerFaceAlpha', 0.5);
            title('Reachability');
            axis equal
            cb = colorbar;
            cb.Label.String = 'Reachability Index';
            hold on
            show(app.rbt_model);
            hold off
        end

        % Value changed function: VisualizationSwitch
        function VisualizationSwitchValueChanged(app, event)
            value = app.VisualizationSwitch.Value;
            if value == "Only Right Half"
                app.both_halves = false;
            elseif value == "Entire Reachable Workspace"
                app.both_halves = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RobotModelTab = uitab(app.TabGroup);
            app.RobotModelTab.Title = 'Robot Model';
            app.URDFFileNameLabel = uilabel(app.RobotModelTab);
            app.URDFFileNameLabel.Text = 'URDF File Name:';
            app.URDFFileNameEditField = uieditfield(app.RobotModelTab, 'text');
            app.URDFFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @URDFFileNameEditFieldValueChanged, true);
            app.URDFFileNameEditField.Placeholder = 'Robot URDF File';
            app.BrowseURDFButton = uibutton(app.RobotModelTab, 'push');
            app.BrowseURDFButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseURDFButtonPushed, true);
            app.BrowseURDFButton.Text = 'Browse URDF';
            app.RobotImportedLampLabel = uilabel(app.RobotModelTab);
            app.RobotImportedLampLabel.Text = 'Robot Imported';
            app.RobotImportedLamp = uilamp(app.RobotModelTab);
            app.NumberofConfigurationsLabel = uilabel(app.RobotModelTab);
            app.NumberofConfigurationsLabel.Text = 'Number of Configurations:';
            app.NumberofConfigurationsEditField = uieditfield(app.RobotModelTab, 'numeric');
            app.NumberofConfigurationsEditField.Limits = [100 Inf];
            app.NumberofConfigurationsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofConfigurationsEditField.Value = 10000;
            app.GenerateWorkspaceButton = uibutton(app.RobotModelTab, 'push');
            app.GenerateWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateWorkspaceButtonPushed, true);
            app.GenerateWorkspaceButton.Text = 'Generate Workspace';
            app.ImportShowRobotButton = uibutton(app.RobotModelTab, 'push');
            app.ImportShowRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ImportShowRobotButtonPushed, true);
            app.ImportShowRobotButton.Text = 'Import & Show Robot';
            app.ShowWorkspaceButton = uibutton(app.RobotModelTab, 'push');
            app.ShowWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowWorkspaceButtonPushed, true);
            app.ShowWorkspaceButton.Text = 'Show Workspace';
            app.VoxelsperDimensionLabel = uilabel(app.RobotModelTab);
            app.VoxelsperDimensionLabel.Text = 'Voxels per Dimension:';
            app.VoxelsperDimensionEditField = uieditfield(app.RobotModelTab, 'numeric');
            app.VoxelsperDimensionEditField.Limits = [10 Inf];
            app.VoxelsperDimensionEditField.Value = 20;
            app.VoxelizeWorkspaceButton = uibutton(app.RobotModelTab, 'push');
            app.VoxelizeWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @VoxelizeWorkspaceButtonPushed, true);
            app.VoxelizeWorkspaceButton.Text = 'Voxelize Workspace';
            app.ShowVoxelizedWorkspaceButton = uibutton(app.RobotModelTab, 'push');
            app.ShowVoxelizedWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVoxelizedWorkspaceButtonPushed, true);
            app.ShowVoxelizedWorkspaceButton.Text = 'Show Voxelized Workspace';
            app.RobotModelSwitch = uiswitch(app.RobotModelTab, 'rocker');
            app.RobotModelSwitch.Items = {'Select Model', 'Import URDF'};
            app.RobotModelSwitch.ValueChangedFcn = createCallbackFcn(app, @RobotModelSwitchValueChanged, true);
            app.RobotModelSwitch.Value = 'Select Model';
            app.SelectaPredefinedRobotLabel = uilabel(app.RobotModelTab);
            app.SelectaPredefinedRobotLabel.Text = 'Select a Predefined Robot:';
            app.SelectaPredefinedRobotDropDown = uidropdown(app.RobotModelTab);
            app.SelectaPredefinedRobotDropDown.Items = {'ABB IRB 120', 'ABB IRB 1600', 'FANUC LR Mate 200iB', 'KUKA LBR iiwa 7 R800', 'Universal Robots UR10', 'Yaskawa Motoman MH5'};
            app.SelectaPredefinedRobotDropDown.Value = 'ABB IRB 120';
            app.ReachabilityAnalysisTab = uitab(app.TabGroup);
            app.ReachabilityAnalysisTab.Title = 'Reachability Analysis';
            app.XEditFieldLabel = uilabel(app.ReachabilityAnalysisTab);
            app.XEditFieldLabel.Text = 'X:';
            app.PRXEditField = uieditfield(app.ReachabilityAnalysisTab, 'numeric');
            app.YLabel = uilabel(app.ReachabilityAnalysisTab);
            app.YLabel.Text = 'Y:';
            app.PRYEditField = uieditfield(app.ReachabilityAnalysisTab, 'numeric');
            app.ZLabel = uilabel(app.ReachabilityAnalysisTab);
            app.ZLabel.Text = 'Z:';
            app.PRZEditField = uieditfield(app.ReachabilityAnalysisTab, 'numeric');
            app.ZLabel_2 = uilabel(app.ReachabilityAnalysisTab);
            app.ZLabel_2.Text = 'C:';
            app.PRCEditField = uieditfield(app.ReachabilityAnalysisTab, 'numeric');
            app.YLabel_2 = uilabel(app.ReachabilityAnalysisTab);
            app.YLabel_2.Text = 'B:';
            app.PRBEditField = uieditfield(app.ReachabilityAnalysisTab, 'numeric');
            app.ALabel = uilabel(app.ReachabilityAnalysisTab);
            app.ALabel.Text = 'A:';
            app.PRAEditField = uieditfield(app.ReachabilityAnalysisTab, 'numeric');
            app.PointReachTextArea = uitextarea(app.ReachabilityAnalysisTab);
            app.PointReachTextArea.Editable = 'off';
            app.PointReachTextArea.Value = {'Single point reachability check:'};
            app.MapGenTextArea = uitextarea(app.ReachabilityAnalysisTab);
            app.MapGenTextArea.Editable = 'off';
            app.MapGenTextArea.Value = {'Reachability map generation:'};
            app.ReachableLampLabel = uilabel(app.ReachabilityAnalysisTab);
            app.ReachableLampLabel.Text = 'Reachable';
            app.ReachableLamp = uilamp(app.ReachabilityAnalysisTab);
            app.SelfcollisionSwitchLabel = uilabel(app.ReachabilityAnalysisTab);
            app.SelfcollisionSwitchLabel.Text = 'Self-collision';
            app.SelfcollisionSwitch = uiswitch(app.ReachabilityAnalysisTab, 'slider');
            app.SelfcollisionSwitch.ValueChangedFcn = createCallbackFcn(app, @SelfcollisionSwitchValueChanged, true);
            app.GenerateReachabilityMapButton = uibutton(app.ReachabilityAnalysisTab, 'push');
            app.GenerateReachabilityMapButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReachabilityMapButtonPushed, true);
            app.GenerateReachabilityMapButton.Text = 'Generate Reachability Map';
            app.CheckReachabilityButton = uibutton(app.ReachabilityAnalysisTab, 'push');
            app.CheckReachabilityButton.Text = 'Check Reachability';
            app.ThetaSlicesSliderLabel = uilabel(app.ReachabilityAnalysisTab);
            app.ThetaSlicesSliderLabel.Text = 'Theta Slices:';
            app.ThetaSlicesSlider = uislider(app.ReachabilityAnalysisTab);
            app.ThetaSlicesSlider.Limits = [2 20];
            app.ThetaSlicesSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.ThetaSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ThetaSlicesSliderValueChanged, true);
            app.ThetaSlicesSlider.ValueChangingFcn = createCallbackFcn(app, @ThetaSlicesSliderValueChanging, true);
            app.ThetaSlicesSlider.Value = 5;
            app.PhiSlicesSliderLabel = uilabel(app.ReachabilityAnalysisTab);
            app.PhiSlicesSliderLabel.Text = 'Phi Slices:';
            app.PhiSlicesSlider = uislider(app.ReachabilityAnalysisTab);
            app.PhiSlicesSlider.Limits = [1 20];
            app.PhiSlicesSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100];
            app.PhiSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @PhiSlicesSliderValueChanged, true);
            app.PhiSlicesSlider.ValueChangingFcn = createCallbackFcn(app, @PhiSlicesSliderValueChanging, true);
            app.PhiSlicesSlider.Value = 5;
            app.ThetaSliceNumeric = uieditfield(app.ReachabilityAnalysisTab, 'text');
            app.ThetaSliceNumeric.Editable = 'off';
            app.ThetaSliceNumeric.Value = '5';
            app.PhiSliceNumeric = uieditfield(app.ReachabilityAnalysisTab, 'text');
            app.PhiSliceNumeric.Editable = 'off';
            app.PhiSliceNumeric.Value = '5';
            app.PosesperVoxelEditFieldLabel = uilabel(app.ReachabilityAnalysisTab);
            app.PosesperVoxelEditFieldLabel.Text = 'Poses per Voxel';
            app.PosesperVoxelNumeric = uieditfield(app.ReachabilityAnalysisTab, 'numeric');
            app.PosesperVoxelNumeric.Limits = [2 400];
            app.PosesperVoxelNumeric.Editable = 'off';
            app.PosesperVoxelNumeric.Value = 25;
            app.VisualizationSwitchLabel = uilabel(app.ReachabilityAnalysisTab);
            app.VisualizationSwitchLabel.Text = 'Visualization';
            app.VisualizationSwitch = uiswitch(app.ReachabilityAnalysisTab, 'slider');
            app.VisualizationSwitch.Items = {'Only Right Half', 'Entire Reachable Workspace'};
            app.VisualizationSwitch.ValueChangedFcn = createCallbackFcn(app, @VisualizationSwitchValueChanged, true);
            app.VisualizationSwitch.Value = 'Only Right Half';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ReachabilityGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end